classdef BeamPropagation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MortgageCalculatorUIFigure   matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        OpenMenu                     matlab.ui.container.Menu
        SaveasMenu                   matlab.ui.container.Menu
        EvaluationMenu               matlab.ui.container.Menu
        RefreshMenu                  matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        ViewdirectionEditField       matlab.ui.control.EditField
        ViewdirectionEditFieldLabel  matlab.ui.control.Label
        PlotFunctionButtonGroup      matlab.ui.container.ButtonGroup
        BeamShapeButton              matlab.ui.control.RadioButton
        SketchButton_2               matlab.ui.control.RadioButton
        DetailedButton               matlab.ui.control.RadioButton
        WavelengthnmEditField        matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel   matlab.ui.control.Label
        BeamShapeEditField           matlab.ui.control.EditField
        BeamShapeEditFieldLabel      matlab.ui.control.Label
        StartingDirectionEditField   matlab.ui.control.EditField
        StartingDirectionLabel       matlab.ui.control.Label
        StartingPositionEditField    matlab.ui.control.EditField
        StartingPositionLabel        matlab.ui.control.Label
        MirrorTable                  matlab.ui.control.Table
        TextArea                     matlab.ui.control.TextArea
        RightPanel                   matlab.ui.container.Panel
        BeamPropagationDisplay       matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
        addmirrorMenu                matlab.ui.container.Menu
        deletemirrorMenu             matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Beam % Beam propagation class;
        CurrentFile = [] % current file path;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Beam = load('./Default.mat');
            app.Beam = app.Beam.obj;
            Position = 1; Direction = "1 1 1";mirror = 1;
            T = table(mirror , Position,Direction);
            for i = 1:length(app.Beam.mirrors)
                mirrorObj = app.Beam.mirrors{i};
%                 T.Position(i) = mirrorObj.position;
%                 T.mirror(i) = i;
%                 T.Direction(i) =string( num2str( mirrorObj.direction));
T = [T;{i,mirrorObj.position,string( num2str( mirrorObj.direction))}];
            end
            T(1,:) = [];
            app.MirrorTable.Data = T;
            app.RefreshMenuSelected;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MortgageCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {472, 472};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {312, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function: not associated with a component
        function PlotButtonPushed(app, event)
                       
            % Calculate the monthly payment
            amount = app.LoanAmountEditField.Value;
            rate = app.InterestRateEditField.Value/12/100;
            nper = 12*app.LoanPeriodYearsEditField.Value;
            payment = (amount*rate)/(1-(1+rate)^-nper);
            app.MonthlyPaymentEditField.Value = payment;
            
            % pre allocating and initializing variables
            interest = zeros(1,nper);
            principal = zeros(1,nper);
            balance = zeros (1,nper);
            
            balance(1) = amount;
            
            % Calculate the principal and interest over time
            for i = 1:nper
                interest(i)  = balance(i)*rate;
                principal(i) = payment - interest(i);
                balance(i+1) = balance(i) - principal(i);
            end
            
            % Plot the principal and interest
            plot(app.BeamPropagationDisplay,(1:nper)',principal, ...
                (1:nper)',interest);
            legend(app.BeamPropagationDisplay,{'Principal','Interest'},'Location','Best')
            xlim(app.BeamPropagationDisplay,[0 nper]); 
            ylim(app.BeamPropagationDisplay,'auto');
            
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [file,path] = uigetfile('*.mat');
            app.CurrentFile = fullfile(path,file);
            a = load(fullfile(path,file));
            
            app.Beam = a.obj;
            app.StartingPositionEditField.Value = num2str(app.Beam.startingPoint);
            app.StartingDirectionEditField.Value = num2str(app.Beam.initialpropagateDirection);
            app.BeamShapeEditField.Value = num2str(app.Beam.initialbeamShape);
            app.WavelengthnmEditField.Value = app.Beam.wavelength*10^9;
            app.Beam.PlotAx = app.BeamPropagationDisplay;

            Position = 1; Direction = "1 1 1";mirror = 1;
            T = table(mirror , Position,Direction);
            for i = 1:length(app.Beam.mirrors)
                mirrorObj = app.Beam.mirrors{i};
%                 T.Position(i) = mirrorObj.position;
%                 T.mirror(i) = i;
%                 T.Direction(i) =string( num2str( mirrorObj.direction));
                T = [T;{i,mirrorObj.position,string( num2str( mirrorObj.direction))}];
            end
            T(1,:) = [];
            Table = app.MirrorTable.Data;
            app.MirrorTable.Data = T;
            app.RefreshMenuSelected;
        end

        % Callback function: BeamShapeEditField, MirrorTable, RefreshMenu, 
        % ...and 3 other components
        function RefreshMenuSelected(app, event)
            StartingPoint = str2num(app.StartingPositionEditField.Value);
            StartingDir   = str2num(app.StartingDirectionEditField.Value);
            BeamShape     = str2num(app.BeamShapeEditField.Value);
            Wavelength    = app.WavelengthnmEditField.Value .* 10^-9;
            app.Beam      = BeamReflection(StartingPoint,StartingDir,BeamShape,Wavelength);
            app.Beam.PlotAx = app.BeamPropagationDisplay;
            Table = app.MirrorTable.Data;
            for i = 1: height(Table)
                app.Beam = app.Beam.addMirror(Table.Position(i), ...
                    str2num(Table.Direction(i)));
            end
            app.Beam = app.Beam.beamPropagationTraceAndShape;
            
            app.UpdatePlot;
        end

        % Selection changed function: PlotFunctionButtonGroup
        function UpdatePlot(app, event)
            selectedButton = app.PlotFunctionButtonGroup.SelectedObject;
            cla(app.BeamPropagationDisplay);
            switch selectedButton.Text
                case "Sketch"
                    app.ViewdirectionEditField.Visible = "off";
                    app.Beam.plotBeamAndMirrors;
                case "Detailed"
                    app.ViewdirectionEditField.Visible = "off";
                    app.Beam.plotBeamShape;
                case "Beam Shape"
                    app.ViewdirectionEditField.Visible = "on";
                    ViewDirection = str2num(app.ViewdirectionEditField.Value);
                    
                    app.Beam.plotProjectedImg(ViewDirection);

            end
        end

        % Value changed function: ViewdirectionEditField
        function ViewdirectionEditFieldValueChanged(app, event)
            value = app.ViewdirectionEditField.Value;
            if length(str2num(value))~=3
                errordlg("The Viewdirection is not the valid list. Please input again!")
                app.ViewdirectionEditField.Value = event.PreviousValue;
            end
            app.UpdatePlot;
        end

        % Menu selected function: addmirrorMenu
        function addmirrorMenuSelected(app, event)
            Table = app.MirrorTable.Data;
            Height = height(Table);
            
            Table = [Table;{Height+1,Table.Position(Height)+1,Table.Direction(Height)}];
            app.MirrorTable.Data = Table;
            app.RefreshMenuSelected;
        end

        % Menu selected function: deletemirrorMenu
        function deletemirrorMenuSelected(app, event)
            if isempty(app.MirrorTable.Selection)
                app.TextArea.Value = "Please select one mirror to delete";
            else
            Index = app.MirrorTable.Selection(1);
            app.MirrorTable.Data(Index,:)= [];
            app.RefreshMenuSelected;
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            if isempty(app.CurrentFile)
                app.SaveasMenuSelected;
            else
                obj = app.Beam;
                save(app.CurrentFile,"obj");
            end
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            [file,path] = app.Beam.saveBeam();
            app.CurrentFile = fullfile(path,file);
            app.TextArea.Value = ['File has been saved to ',fullfile(path,file)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MortgageCalculatorUIFigure = uifigure('Visible', 'off');
            app.MortgageCalculatorUIFigure.Name = 'Mortgage Calculator';
            app.MortgageCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.MortgageCalculatorUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open ...';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as...';
            app.EvaluationMenu = uimenu(app.MortgageCalculatorUIFigure);
            app.EvaluationMenu.Text = 'Evaluation';
            app.RefreshMenu = uimenu(app.EvaluationMenu);
            app.RefreshMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshMenuSelected, true);
            app.RefreshMenu.Text = 'Refresh';
            app.GridLayout = uigridlayout(app.MortgageCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Value = {'Welcome!'};
            app.MirrorTable = uitable(app.LeftPanel);
            app.MirrorTable.ColumnName = {'Mirror'; 'Position'; 'Direction'};
            app.MirrorTable.RowName = {};
            app.MirrorTable.ColumnSortable = true;
            app.MirrorTable.ColumnEditable = true;
            app.MirrorTable.CellEditCallback = createCallbackFcn(app, @RefreshMenuSelected, true);
            app.StartingPositionLabel = uilabel(app.LeftPanel);
            app.StartingPositionLabel.Text = 'Starting Position';
            app.StartingPositionEditField = uieditfield(app.LeftPanel, 'text');
            app.StartingPositionEditField.ValueChangedFcn = createCallbackFcn(app, @RefreshMenuSelected, true);
            app.StartingPositionEditField.Value = '0 0 0';
            app.StartingDirectionLabel = uilabel(app.LeftPanel);
            app.StartingDirectionLabel.Text = 'Starting Direction';
            app.StartingDirectionEditField = uieditfield(app.LeftPanel, 'text');
            app.StartingDirectionEditField.ValueChangedFcn = createCallbackFcn(app, @RefreshMenuSelected, true);
            app.StartingDirectionEditField.Value = '1 0 0';
            app.BeamShapeEditFieldLabel = uilabel(app.LeftPanel);
            app.BeamShapeEditFieldLabel.Text = 'Beam Shape';
            app.BeamShapeEditField = uieditfield(app.LeftPanel, 'text');
            app.BeamShapeEditField.ValueChangedFcn = createCallbackFcn(app, @RefreshMenuSelected, true);
            app.BeamShapeEditField.Value = '0.1 0.2';
            app.WavelengthnmEditFieldLabel = uilabel(app.LeftPanel);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WavelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @RefreshMenuSelected, true);
            app.WavelengthnmEditField.Value = 532;
            app.PlotFunctionButtonGroup = uibuttongroup(app.LeftPanel);
            app.PlotFunctionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
            app.PlotFunctionButtonGroup.Title = 'Plot Function';
            app.DetailedButton = uiradiobutton(app.PlotFunctionButtonGroup);
            app.DetailedButton.Text = 'Detailed';
            app.DetailedButton.Value = true;
            app.SketchButton_2 = uiradiobutton(app.PlotFunctionButtonGroup);
            app.SketchButton_2.Text = 'Sketch';
            app.BeamShapeButton = uiradiobutton(app.PlotFunctionButtonGroup);
            app.BeamShapeButton.Text = 'Beam Shape';
            app.ViewdirectionEditFieldLabel = uilabel(app.LeftPanel);
            app.ViewdirectionEditFieldLabel.Text = 'View direction';
            app.ViewdirectionEditField = uieditfield(app.LeftPanel, 'text');
            app.ViewdirectionEditField.ValueChangedFcn = createCallbackFcn(app, @ViewdirectionEditFieldValueChanged, true);
            app.ViewdirectionEditField.Value = '1 0 0';
            app.RightPanel = uipanel(app.GridLayout);
            app.BeamPropagationDisplay = uiaxes(app.RightPanel);
            title(app.BeamPropagationDisplay, 'Principal and Interest')
            xlabel(app.BeamPropagationDisplay, 'X')
            ylabel(app.BeamPropagationDisplay, 'Y')
            app.ContextMenu = uicontextmenu(app.MortgageCalculatorUIFigure);
            app.addmirrorMenu = uimenu(app.ContextMenu);
            app.addmirrorMenu.MenuSelectedFcn = createCallbackFcn(app, @addmirrorMenuSelected, true);
            app.addmirrorMenu.Text = 'add mirror';
            app.deletemirrorMenu = uimenu(app.ContextMenu);
            app.deletemirrorMenu.MenuSelectedFcn = createCallbackFcn(app, @deletemirrorMenuSelected, true);
            app.deletemirrorMenu.Text = 'delete mirror';
            % Assign app.ContextMenu
            app.MirrorTable.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.MortgageCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeamPropagation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MortgageCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MortgageCalculatorUIFigure)
        end
    end
end